classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        EigenvaluePlot    matlab.ui.control.UIAxes
        LatticePlot       matlab.ui.control.UIAxes
        MSliderLabel      matlab.ui.control.Label
        MSlider           matlab.ui.control.Slider
        alphaSliderLabel  matlab.ui.control.Label
        alphaSlider       matlab.ui.control.Slider
        pumpSliderLabel   matlab.ui.control.Label
        pumpSlider        matlab.ui.control.Slider
        lossSliderLabel   matlab.ui.control.Label
        lossSlider        matlab.ui.control.Slider
    end


    properties (Access = public)
        data % Description
    end

    methods (Access = private)
    
        function  replotEig(app)
            [app.data.egs,app.data.lattice]=calculate(app.pumpSlider.Value,app.MSlider.Value,...
                app.lossSlider.Value,app.alphaSlider.Value);
            scatter(app.EigenvaluePlot,real(app.data.egs.values), imag(app.data.egs.values), 'r');
            app.EigenvaluePlot.Children.ButtonDownFcn=...
                createCallbackFcn(app, @eigenvaluePlotCallback, true);
        end
        function replotMode(app)
            Visual.plotEig(app.data.egs,app.data.seig,app.LatticePlot);
        end
        function eigenvaluePlotCallback(app,event)
            %find hitpoint
            points=event.IntersectionPoint;
            hitpnt=abs(real(app.data.egs.values)-points(1))...
                +abs(imag(app.data.egs.values)-points(2));
            [~,index]=min(hitpnt);
            app.data.seig=index;
            disp(index)
            replotMode(app);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            include;
            replotEig(app);
            Visual.showNodes(app.data.lattice,app.LatticePlot);
            app.EigenvaluePlot.Children.ButtonDownFcn=...
                createCallbackFcn(app, @eigenvaluePlotCallback, true);
        end

        % Callback function: MSlider, alphaSlider, lossSlider, 
        % pumpSlider
        function MSliderValueChanging(app, event)
           % changingValue = event.Value;
            replotEig(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.EigenvaluePlot = uiaxes(app.UIFigure);
            title(app.EigenvaluePlot, 'Eigenvalues')
            xlabel(app.EigenvaluePlot, 'Real')
            ylabel(app.EigenvaluePlot, 'Imag')
            app.LatticePlot = uiaxes(app.UIFigure);
            title(app.LatticePlot, 'Lattice')
            xlabel(app.LatticePlot, 'X')
            ylabel(app.LatticePlot, 'Y')
            app.MSliderLabel = uilabel(app.UIFigure);
            app.MSliderLabel.Text = 'M';
            app.MSlider = uislider(app.UIFigure);
            app.MSlider.Limits = [0 5];
            app.MSlider.ValueChangingFcn = createCallbackFcn(app, @MSliderValueChanging, true);
            app.alphaSliderLabel = uilabel(app.UIFigure);
            app.alphaSliderLabel.Text = 'alpha';
            app.alphaSlider = uislider(app.UIFigure);
            app.alphaSlider.Limits = [0 5];
            app.alphaSlider.ValueChangedFcn = createCallbackFcn(app, @MSliderValueChanging, true);
            app.pumpSliderLabel = uilabel(app.UIFigure);
            app.pumpSliderLabel.Text = 'pump';
            app.pumpSlider = uislider(app.UIFigure);
            app.pumpSlider.Limits = [0 10];
            app.pumpSlider.ValueChangedFcn = createCallbackFcn(app, @MSliderValueChanging, true);
            app.lossSliderLabel = uilabel(app.UIFigure);
            app.lossSliderLabel.Text = 'loss';
            app.lossSlider = uislider(app.UIFigure);
            app.lossSlider.Limits = [0 10];
            app.lossSlider.ValueChangedFcn = createCallbackFcn(app, @MSliderValueChanging, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end